classdef one_cycle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TextArea                      matlab.ui.control.TextArea
        ErrorLabel                    matlab.ui.control.Label
        OutputLength                  matlab.ui.control.Label
        Lamp                          matlab.ui.control.Lamp
        ApplytoreferencetooCheckBox   matlab.ui.control.CheckBox
        ClearButton                   matlab.ui.control.Button
        LoadreferenceButton           matlab.ui.control.Button
        ExecuteButton                 matlab.ui.control.Button
        NumberofframesEditField       matlab.ui.control.NumericEditField
        NumberofframesEditFieldLabel  matlab.ui.control.Label
        LoadfilesButton               matlab.ui.control.Button
    end

    properties (Access = private)
        files = {}
        reference
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadfilesButton
        function LoadfilesButtonPushed(app, event)
            [file,path] = uigetfile('*.avi','Select files','MultiSelect','on');
            if ~isfloat(file)
                if ~iscell(file)
                    file  = {file};
                end
                if length(file)~= app.reference.nbFiles
                    app.ErrorLabel.Text = "Error: Number of files not correct" ; 
                    return ; 
                end
                app.LoadfilesButton.Enable = false ; 
                app.Lamp.Color = [1, 0, 0];
                drawnow;
                app.files{end + 1} = OneCycleClass(file,path);
                app.OutputLength.Text = num2str(length(app.files)) ;
                if strcmp(app.TextArea.Value, '')
                    app.TextArea.Value = path ;
                else
                    app.TextArea.Value{end + 1} = path;
                end
            end
            app.ErrorLabel.Text = "" ; 
            app.LoadfilesButton.Enable = true ;
            app.Lamp.Color = [0, 1, 0] ;
        end

        % Button pushed function: LoadreferenceButton
        function LoadreferenceButtonPushed(app, event)
            [file,path] = uigetfile('*.avi','Select reference files','MultiSelect','on');
            if ~isfloat(file)
                for n = 1:length(app.files)
                    if length(file)~=app.files{n}.nbFiles 
                        app.ErrorLabel.Text = "Error: number of files not correct" ; 
                        return ; 
                    end 
                end
                app.Lamp.Color = [1, 0, 0];
                drawnow;
                if ~iscell(file)
                    file = {file};
                end
                app.reference = OneCycleClass(file,path);
                app.LoadfilesButton.Enable = true ;
                app.ExecuteButton.Enable = true ;
                app.ClearButton.Enable = true ;
            end
            app.ErrorLabel.Text = "" ;
            app.Lamp.Color = [0, 1, 0];
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            app.Lamp.Color = [1, 0, 0];
            drawnow;
            sys_index_list_cell = app.reference.getSystole();
            if app.ApplytoreferencetooCheckBox.Value 
                app.reference.writeCut(sys_index_list_cell,app.NumberofframesEditField.Value) ; 
            end
            for n = 1:length(app.files)
                app.files{n}.writeCut(sys_index_list_cell, app.NumberofframesEditField.Value);
            end
            app.Lamp.Color = [0, 1, 0];
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.files = {};
            app.OutputLength.Text = num2str(0) ;
            app.TextArea.Value = "";
            app.LoadfilesButton.Enable = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadfilesButton = uibutton(app.UIFigure, 'push');
            app.LoadfilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfilesButtonPushed, true);
            app.LoadfilesButton.Enable = 'off';
            app.LoadfilesButton.Text = 'Load files';
            app.NumberofframesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofframesEditFieldLabel.Text = 'Number of frames ';
            app.NumberofframesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofframesEditField.Limits = [1 Inf];
            app.NumberofframesEditField.Value = 64;
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Enable = 'off';
            app.ExecuteButton.Text = 'Execute';
            app.LoadreferenceButton = uibutton(app.UIFigure, 'push');
            app.LoadreferenceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadreferenceButtonPushed, true);
            app.LoadreferenceButton.Text = 'Load reference';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Enable = 'off';
            app.ClearButton.Text = 'Clear';
            app.ApplytoreferencetooCheckBox = uicheckbox(app.UIFigure);
            app.ApplytoreferencetooCheckBox.Text = 'Apply to reference too';
            app.Lamp = uilamp(app.UIFigure);
            app.OutputLength = uilabel(app.UIFigure);
            app.OutputLength.Text = '0';
            app.ErrorLabel = uilabel(app.UIFigure);
            app.ErrorLabel.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = one_cycle

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end